classdef QuadcopterDesignGA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunGAButton                     matlab.ui.control.Button
        InputLoadedEditFieldLabel       matlab.ui.control.Label
        InputLoadedEditField            matlab.ui.control.EditField
        FigureofMeritEditFieldLabel     matlab.ui.control.Label
        FigureofMeritEditField          matlab.ui.control.NumericEditField
        HoverTimeMinsEditFieldLabel     matlab.ui.control.Label
        HoverTimeMinsEditField          matlab.ui.control.NumericEditField
        QuadcopterDesignSelectionLabel  matlab.ui.control.Label
        WeightingIntervalEditFieldLabel  matlab.ui.control.Label
        WeightingIntervalEditField      matlab.ui.control.NumericEditField
        DesignNoDropDownLabel           matlab.ui.control.Label
        DesignNoDropDown                matlab.ui.control.DropDown
        PerformanceInputsLabel          matlab.ui.control.Label
        ParetoOptimalDesignLabel        matlab.ui.control.Label
        CostLabel                       matlab.ui.control.Label
        CostEditField                   matlab.ui.control.NumericEditField
        MassKgEditFieldLabel            matlab.ui.control.Label
        MassKgEditField                 matlab.ui.control.NumericEditField
        HoverTimeMinsEditField_2Label   matlab.ui.control.Label
        HoverTimeMinsEditField_2        matlab.ui.control.NumericEditField
        FigureofMeritEditField_2Label   matlab.ui.control.Label
        FigureofMeritEditField_2        matlab.ui.control.NumericEditField
        MaxThrustNEditFieldLabel        matlab.ui.control.Label
        MaxThrustNEditField             matlab.ui.control.NumericEditField
        SelPartsPMBESCFrFCEditFieldLabel  matlab.ui.control.Label
        SelPartsPMBESCFrFCEditField     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        prop % Description
        motor
        battery
        esc
        frame
        FC
        num_opt
        FM % Figure of Merit
        T_min
        wInterval % Weighting Interval
        sel_design %For drop down menu
        f1  % Weight, cost, performance 
        f2
        f3
        soln
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, prop_swc, prop_perf, motor_wc, motor_perf, battery_data, esc_data, frame_data, FC_data)
            [app.prop,app.motor,app.battery,app.esc,app.frame,app.FC,app.num_opt] = user_ip(prop_swc,prop_perf,motor_wc,motor_perf,battery_data,...
esc_data,frame_data,FC_data);

        end

        % Button pushed function: RunGAButton
        function RunGAButtonPushed(app, event)
            if isempty(app.prop)
                app.InputLoadedEditField.Value = "Failure";
            else
                app.InputLoadedEditField.Value = "Success";
            end
            
            vlb = [1 1 1 1 1 1];	%Lower bound of each gene - all variables
            vub = app.num_opt;	%Upper bound of each gene - all variables
            bits = log(vub-vlb+1)./log(2);	%number of bits describing each gene - all variables
            
            
            app.FM = app.FigureofMeritEditField.Value;
            app.T_min = app.HoverTimeMinsEditField.Value;
            app.wInterval = app.WeightingIntervalEditField.Value;
            
            
            pop_size = 4*sum(bits);
            prob_mut =  (sum(bits)+1)/(2*pop_size*sum(bits));
            options = goptions([1,0.9,0,0,0,0,0,0,0,0,pop_size,0.5,prob_mut,200]); 
            
            prop_sizes = 2:7;
            fs = [105 135 165 200 235 265];
            coeff = polyfit(prop_sizes,fs,1);
            cost_min = 0; weight_min=0;
            
            a1 = 1; a2 = 0;
            consts = [app.FM,app.T_min,coeff,cost_min,weight_min,a1,a2];
            
            
            [x_mincost,cost_min,~,~,~,~,~]= GA550('objfunc',[ ],options,vlb,vub,bits,app.prop,app.motor,app.battery,app.esc,app.frame,app.FC,consts);

            a1 = 0; a2 = 1;
            consts = [app.FM,app.T_min,coeff,0,0,a1,a2];
            
            [x_minweight,weight_min,~,~,~,~,~]= GA550('objfunc',[ ],options,vlb,vub, ...
                bits,app.prop,app.motor,app.battery,app.esc,app.frame,app.FC,consts);
            
            
            idx = 1;
            for i=0:app.wInterval:1
                a1= i; a2 = 1-i;
                consts = [app.FM,app.T_min,coeff,cost_min,weight_min,a1,a2];
                [x(:,idx),fbest(:,idx),~,nfit(idx),~,~,~]= GA550('objfunc',[ ],options,vlb,vub, ...
                bits,app.prop,app.motor,app.battery,app.esc,app.frame,app.FC,consts);
                idx = idx + 1;
            end 
            
            app.f1 = evaluate_design(x,"cost",app.prop,app.motor,app.battery,app.esc,app.frame,app.FC); 
            app.f2 = evaluate_design(x,"weight",app.prop,app.motor,app.battery,app.esc,app.frame,app.FC);
            
            
            plot(app.UIAxes,app.f1,app.f2,'-o')
            xlabel(app.UIAxes,"Cost ($)");
            ylabel(app.UIAxes,"Weight(N)");
            title(app.UIAxes,"Pareto Frontier");
            
            data_labels = "D" + string(1:idx-1);
            %labelpoints(app.f1,app.f2,data_labels,'SE',0.2,1);
            text(app.UIAxes,app.f1+0.3, app.f2, data_labels)
            
            app.f3 = evaluate_design(x,"perf",app.prop,app.motor,app.battery,app.esc,app.frame,app.FC);
                        
            app.soln = x;
        end

        % Value changed function: FigureofMeritEditField
        function FigureofMeritEditFieldValueChanged(app, event)
            app.FM = app.FigureofMeritEditField.Value;
            
        end

        % Value changed function: HoverTimeMinsEditField
        function HoverTimeMinsEditFieldValueChanged(app, event)
            app.T_min = app.HoverTimeMinsEditField.Value;
            
        end

        % Value changed function: WeightingIntervalEditField
        function WeightingIntervalEditFieldValueChanged(app, event)
            app.wInterval = app.WeightingIntervalEditField.Value;
            
        end

        % Drop down opening function: DesignNoDropDown
        function DesignNoDropDownOpening(app, event)
            avalues = 0:app.wInterval:1;
            data_labels = "Design " + string(1:size(avalues,2));
            app.DesignNoDropDown.Items = cellstr(data_labels);
        end

        % Value changed function: DesignNoDropDown
        function DesignNoDropDownValueChanged(app, event)
            app.sel_design = regexp(app.DesignNoDropDown.Value,'\d*','Match');
            app.sel_design = str2double(string(app.sel_design));
            
            app.CostEditField.Value = app.f1(app.sel_design);
            app.MassKgEditField.Value = app.f2(app.sel_design)/9.8;
            app.HoverTimeMinsEditField_2.Value = app.f3(1,app.sel_design);
            app.FigureofMeritEditField_2.Value = app.f3(2,app.sel_design);
            app.MaxThrustNEditField.Value = app.f3(3,app.sel_design);
            app.SelPartsPMBESCFrFCEditField.Value = num2str(app.soln(:,app.sel_design))';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunGAButton = uibutton(app.UIFigure, 'push');
            app.RunGAButton.ButtonPushedFcn = createCallbackFcn(app, @RunGAButtonPushed, true);
            app.RunGAButton.Text = 'Run GA';
            app.InputLoadedEditFieldLabel = uilabel(app.UIFigure);
            app.InputLoadedEditFieldLabel.Text = 'Input Loaded?';
            app.InputLoadedEditField = uieditfield(app.UIFigure, 'text');
            app.FigureofMeritEditFieldLabel = uilabel(app.UIFigure);
            app.FigureofMeritEditFieldLabel.Text = 'Figure of Merit';
            app.FigureofMeritEditField = uieditfield(app.UIFigure, 'numeric');
            app.FigureofMeritEditField.ValueChangedFcn = createCallbackFcn(app, @FigureofMeritEditFieldValueChanged, true);
            app.FigureofMeritEditField.Value = 60;
            app.HoverTimeMinsEditFieldLabel = uilabel(app.UIFigure);
            app.HoverTimeMinsEditFieldLabel.Text = 'Hover Time (Mins)';
            app.HoverTimeMinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.HoverTimeMinsEditField.ValueChangedFcn = createCallbackFcn(app, @HoverTimeMinsEditFieldValueChanged, true);
            app.HoverTimeMinsEditField.Value = 30;
            app.QuadcopterDesignSelectionLabel = uilabel(app.UIFigure);
            app.QuadcopterDesignSelectionLabel.Text = 'Quadcopter Design Selection';
            app.WeightingIntervalEditFieldLabel = uilabel(app.UIFigure);
            app.WeightingIntervalEditFieldLabel.Text = 'Weighting Interval';
            app.WeightingIntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightingIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @WeightingIntervalEditFieldValueChanged, true);
            app.WeightingIntervalEditField.Value = 0.2;
            app.DesignNoDropDownLabel = uilabel(app.UIFigure);
            app.DesignNoDropDownLabel.Text = 'Design No.';
            app.DesignNoDropDown = uidropdown(app.UIFigure);
            app.DesignNoDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9', 'Option 10', '', ''};
            app.DesignNoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DesignNoDropDownOpening, true);
            app.DesignNoDropDown.ValueChangedFcn = createCallbackFcn(app, @DesignNoDropDownValueChanged, true);
            app.PerformanceInputsLabel = uilabel(app.UIFigure);
            app.PerformanceInputsLabel.Text = 'Performance Inputs';
            app.ParetoOptimalDesignLabel = uilabel(app.UIFigure);
            app.ParetoOptimalDesignLabel.Text = 'Pareto Optimal Design';
            app.CostLabel = uilabel(app.UIFigure);
            app.CostLabel.Text = 'Cost ($)';
            app.CostEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassKgEditFieldLabel = uilabel(app.UIFigure);
            app.MassKgEditFieldLabel.Text = 'Mass (Kg)';
            app.MassKgEditField = uieditfield(app.UIFigure, 'numeric');
            app.HoverTimeMinsEditField_2Label = uilabel(app.UIFigure);
            app.HoverTimeMinsEditField_2Label.Text = 'Hover Time (Mins)';
            app.HoverTimeMinsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FigureofMeritEditField_2Label = uilabel(app.UIFigure);
            app.FigureofMeritEditField_2Label.Text = 'Figure of Merit';
            app.FigureofMeritEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxThrustNEditFieldLabel = uilabel(app.UIFigure);
            app.MaxThrustNEditFieldLabel.Text = 'Max Thrust (N)';
            app.MaxThrustNEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelPartsPMBESCFrFCEditFieldLabel = uilabel(app.UIFigure);
            app.SelPartsPMBESCFrFCEditFieldLabel.Text = 'Sel. Parts (P,M,B,ESC,Fr,FC)';
            app.SelPartsPMBESCFrFCEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pareto Frontier')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuadcopterDesignGA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end